classdef GUI_Hausaufgabe_Dong < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        PlotloeschenButton  matlab.ui.control.Button
        gridonoffCheckBox   matlab.ui.control.CheckBox
        bEditField          matlab.ui.control.NumericEditField
        bEditFieldLabel     matlab.ui.control.Label
        aEditField          matlab.ui.control.NumericEditField
        aEditFieldLabel     matlab.ui.control.Label
        KardioideCheckBox   matlab.ui.control.CheckBox
        SpiraleCheckBox     matlab.ui.control.CheckBox
        KreisCheckBox       matlab.ui.control.CheckBox
        EllipseCheckBox     matlab.ui.control.CheckBox
        UIAxes              matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        % Plotfunktion, mit der wir alle vier Funktion in der Figur zeichen können
        function plotFcn(app)

            % Simulationsdauer von 0 bis 2*pi
            t = 0:0.01:2*pi;
              
            % Ellipse mit gegebenen Parameter a und b in der Figur zeichen
            if app.EllipseCheckBox.Value == 1
                a = app.aEditField.Value;
                b = app.bEditField.Value;
    
                xt1 = a * cos(t);
                yt1 = b * sin(t);
                zt1 = asinh(xt1.* yt1);
                hold(app.UIAxes,'on');
                plot(app.UIAxes,t,zt1,'r');  
            end
            
            % Kreis in der Figur zeichen
            if app.KreisCheckBox.Value == 1
                xt2 = 2*cos(t);
                yt2 = 2*sin(t);   
                zt2 = asinh(xt2.* yt2);
                hold(app.UIAxes,'on');
                plot(app.UIAxes,t,zt2,'b');
            end
            
            % Spirale in der Figur zeichen
            if app.SpiraleCheckBox.Value == 1
                xt3 = (log(2*t+1) + 1).*cos(t);
                yt3 = (log(2*t+1) + 1).*sin(t); 
                zt3 = asinh(xt3.* yt3);
                hold(app.UIAxes,'on');
                plot(app.UIAxes,t,zt3,'-b*');
            end
            
            % Kardioide in der Figur zeichen
            if app.KardioideCheckBox.Value == 1
                xt4 = 2 * (1-cos(t)).* cos(t);
                yt4 = 2 * (1-cos(t)).* sin(t);
                zt4 = asinh(xt4.* yt4);
                hold(app.UIAxes,'on');
                plot(app.UIAxes,t,zt4,'-r*');
            end
            legend(app.UIAxes,'off');
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialisierung aller Parameter 
            app.aEditField.Value = 5;
            app.bEditField.Value = 3;
            app.EllipseCheckBox.Value = 1;
            app.SpiraleCheckBox.Value = 1;
            % Grid on
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';
            app.gridonoffCheckBox.Value = 1;
            plotFcn(app);
            legend(app.UIAxes,{'Ellipse','Spirale'});
        end

        % Value changed function: gridonoffCheckBox
        function gridonoffCheckBoxValueChanged(app, event)
            value = app.gridonoffCheckBox.Value;
            if value == 0 % grid off
                app.UIAxes.XGrid = 'off';
                app.UIAxes.YGrid = 'off';
            else
                app.UIAxes.XGrid = 'on';
                app.UIAxes.YGrid = 'on';
            end
        end

        % Value changed function: EllipseCheckBox
        function EllipseCheckBoxValueChanged(app, event)
            plotFcn(app);
        end

        % Value changed function: KreisCheckBox
        function KreisCheckBoxValueChanged(app, event)
            plotFcn(app);
        end

        % Button pushed function: PlotloeschenButton
        function PlotloeschenButtonPushed(app, event)
           % Clear Figur und stellen alle CheckBox 'off' und Parameter a/b zu 0 ein
           cla(app.UIAxes);
           app.EllipseCheckBox.Value = 0;
           app.KreisCheckBox.Value = 0;
           app.KardioideCheckBox.Value = 0;
           app.SpiraleCheckBox.Value = 0;
           app.aEditField.Value = 0;
           app.bEditField.Value = 0;
        end

        % Value changed function: SpiraleCheckBox
        function SpiraleCheckBoxValueChanged(app, event)
            plotFcn(app);
        end

        % Value changed function: KardioideCheckBox
        function KardioideCheckBoxValueChanged(app, event)
            plotFcn(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Schnittverläufe mit asinh(x*y) entlang verschiedener Parameterkurven')
            xlabel(app.UIAxes, 't in rad')
            ylabel(app.UIAxes, 'zt')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 6.28318530717959];
            app.UIAxes.YLim = [-4 4];
            app.UIAxes.XTick = [0 0.785398163397448 1.5707963267949 2.35619449019234 3.14159265358979 3.92699081698724 4.71238898038469 5.49778714378214 6.28318530717959];
            app.UIAxes.XTickLabel = {'0'; '\pi/4'; '\pi/2'; '3\pi/4'; '\pi'; '5\pi/4'; '3\pi/2'; '7\pi/4'; '2\pi'};
            colormap(app.UIAxes, 'bone')
            app.EllipseCheckBox = uicheckbox(app.UIFigure);
            app.EllipseCheckBox.ValueChangedFcn = createCallbackFcn(app, @EllipseCheckBoxValueChanged, true);
            app.EllipseCheckBox.Text = 'Ellipse';
            app.KreisCheckBox = uicheckbox(app.UIFigure);
            app.KreisCheckBox.ValueChangedFcn = createCallbackFcn(app, @KreisCheckBoxValueChanged, true);
            app.KreisCheckBox.Text = 'Kreis';
            app.SpiraleCheckBox = uicheckbox(app.UIFigure);
            app.SpiraleCheckBox.ValueChangedFcn = createCallbackFcn(app, @SpiraleCheckBoxValueChanged, true);
            app.SpiraleCheckBox.Text = 'Spirale';
            app.KardioideCheckBox = uicheckbox(app.UIFigure);
            app.KardioideCheckBox.ValueChangedFcn = createCallbackFcn(app, @KardioideCheckBoxValueChanged, true);
            app.KardioideCheckBox.Text = 'Kardioide';
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a = ';
            app.aEditField = uieditfield(app.UIFigure, 'numeric');
            app.bEditFieldLabel = uilabel(app.UIFigure);
            app.bEditFieldLabel.Text = 'b = ';
            app.bEditField = uieditfield(app.UIFigure, 'numeric');
            app.gridonoffCheckBox = uicheckbox(app.UIFigure);
            app.gridonoffCheckBox.ValueChangedFcn = createCallbackFcn(app, @gridonoffCheckBoxValueChanged, true);
            app.gridonoffCheckBox.Text = 'grid on/off';
            app.PlotloeschenButton = uibutton(app.UIFigure, 'push');
            app.PlotloeschenButton.ButtonPushedFcn = createCallbackFcn(app, @PlotloeschenButtonPushed, true);
            app.PlotloeschenButton.Text = 'Plot löschen';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Hausaufgabe_Dong

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end